classdef NanocompositeTensileStrengthPrediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                             matlab.ui.Figure
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        InputvariablesPanel             matlab.ui.container.Panel
        PolymermatrixDropDownLabel      matlab.ui.control.Label
        PolymermatrixDropDown           matlab.ui.control.DropDown
        CNTsurfacemodificationmethodDropDownLabel  matlab.ui.control.Label
        CNTsurfacemodificationmethodDropDown  matlab.ui.control.DropDown
        Densityofpolymergcm3EditFieldLabel  matlab.ui.control.Label
        Densityofpolymergcm3EditField   matlab.ui.control.NumericEditField
        YoungsmodulusofmatrixMPaEditFieldLabel  matlab.ui.control.Label
        YoungsmodulusofmatrixMPaEditField  matlab.ui.control.NumericEditField
        TensilestrengthofmatrixMPaEditFieldLabel  matlab.ui.control.Label
        TensilestrengthofmatrixMPaEditField  matlab.ui.control.NumericEditField
        CNTweightfractionEditFieldLabel  matlab.ui.control.Label
        CNTweightfractionEditField      matlab.ui.control.NumericEditField
        CNTdensitygcm3EditFieldLabel    matlab.ui.control.Label
        CNTdensitygcm3EditField         matlab.ui.control.NumericEditField
        CNTaveragediameternmEditFieldLabel  matlab.ui.control.Label
        CNTaveragediameternmEditField   matlab.ui.control.NumericEditField
        CNTaveragelengthnmEditFieldLabel  matlab.ui.control.Label
        CNTaveragelengthnmEditField     matlab.ui.control.NumericEditField
        YoungsmodulusofCNTGPaEditFieldLabel  matlab.ui.control.Label
        YoungsmodulusofCNTGPaEditField  matlab.ui.control.NumericEditField
        NanocompositeprocessingmethodDropDownLabel  matlab.ui.control.Label
        NanocompositeprocessingmethodDropDown  matlab.ui.control.DropDown
        OutputPanel                     matlab.ui.container.Panel
        TensilestrengthofnanocompositeMPaEditFieldLabel  matlab.ui.control.Label
        TensilestrengthofnanocompositeMPaEditField  matlab.ui.control.NumericEditField
        StartpredictButton              matlab.ui.control.Button
        RangeLabel                      matlab.ui.control.Label
        RangeDensityPolymerSlider       matlab.ui.control.Slider
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        RangeSlider_2Label              matlab.ui.control.Label
        RangeYoungPolymerSlider         matlab.ui.control.Slider
        RangeSlider_2Label_2            matlab.ui.control.Label
        RangeTensilePolymerSlider       matlab.ui.control.Slider
        RangeSlider_2Label_3            matlab.ui.control.Label
        RangeWeightFractionSlider       matlab.ui.control.Slider
        RangeSlider_2Label_4            matlab.ui.control.Label
        RangeCNTDensitySlider           matlab.ui.control.Slider
        RangeSlider_2Label_5            matlab.ui.control.Label
        RangeCNTDiameterSlider          matlab.ui.control.Slider
        RangeSlider_2Label_6            matlab.ui.control.Label
        RangeCNTLengthSlider            matlab.ui.control.Slider
        RangeSlider_2Label_7            matlab.ui.control.Label
        RangeCNTYoungSlider             matlab.ui.control.Slider
        Label_5                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RangeDensityPolymerSlider
        function RangeDensityPolymerSliderValueChanging(app, event)
            latestvalue = event.Value; % Current value
            app.Densityofpolymergcm3EditField.Value = latestvalue;  % Update value
        end

        % Value changed function: Densityofpolymergcm3EditField
        function Densityofpolymergcm3EditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeDensityPolymerSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: YoungsmodulusofmatrixMPaEditField
        function YoungsmodulusofmatrixMPaEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeYoungPolymerSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeYoungPolymerSlider
        function RangeYoungPolymerSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.YoungsmodulusofmatrixMPaEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: 
        % TensilestrengthofmatrixMPaEditField
        function TensilestrengthofmatrixMPaEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeTensilePolymerSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeTensilePolymerSlider
        function RangeTensilePolymerSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.TensilestrengthofmatrixMPaEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: CNTweightfractionEditField
        function CNTweightfractionEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeWeightFractionSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeWeightFractionSlider
        function RangeWeightFractionSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CNTweightfractionEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: CNTdensitygcm3EditField
        function CNTdensitygcm3EditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTDensitySlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeCNTDensitySlider
        function RangeCNTDensitySliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CNTdensitygcm3EditField.Value = latestvalue;  % Update value
        end

        % Value changed function: CNTaveragediameternmEditField
        function CNTaveragediameternmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTDiameterSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeCNTDiameterSlider
        function RangeCNTDiameterSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CNTaveragediameternmEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: CNTaveragelengthnmEditField
        function CNTaveragelengthnmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTLengthSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeCNTLengthSlider
        function RangeCNTLengthSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CNTaveragelengthnmEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: YoungsmodulusofCNTGPaEditField
        function YoungsmodulusofCNTGPaEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTYoungSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeCNTYoungSlider
        function RangeCNTYoungSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.YoungsmodulusofCNTGPaEditField.Value = latestvalue;  % Update value
        end

        % Button pushed function: StartpredictButton
        function StartpredictButtonPushed(app, event)
            clc
            if strcmp(app.PolymermatrixDropDown.Value, 'Epoxy') == 1
                PolymerMatrix = 1;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'High density polyethylene') == 1
                PolymerMatrix = 2;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Hard epoxy') == 1
                PolymerMatrix = 3;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Low density polyethylene') == 1
                PolymerMatrix = 4;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Nylon 6') == 1
                PolymerMatrix = 5;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Nylon 610') == 1
                PolymerMatrix = 6;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polyacrylonitrile nanofibers') == 1
                PolymerMatrix = 7;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polycarbonate') == 1
                PolymerMatrix = 8;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polyamide 6') == 1
                PolymerMatrix = 9;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polyetherimide') == 1
                PolymerMatrix = 10;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Poly(ethylene oxide)') == 1
                PolymerMatrix = 11;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Poly(ethylene terephthalate)') == 1
                PolymerMatrix = 12;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polyimide') == 1
                PolymerMatrix = 13;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Acrylic acid grafted polylactide') == 1
                PolymerMatrix = 14;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Poly(methyl methacrylate) (PMMA)‐functionalized') == 1
                PolymerMatrix = 15;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polypropylene') == 1
                PolymerMatrix = 16;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polystyrene') == 1
                PolymerMatrix = 17;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polyurethane') == 1
                PolymerMatrix = 18;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Polyvinyl alcohol') == 1
                PolymerMatrix = 19;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Poly(vinyl chloride)') == 1
                PolymerMatrix = 20;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Poly(styrene-b-butadiene-co-butylene-b-styrene)') == 1
                PolymerMatrix = 21;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Styrene–butadiene rubber') == 1
                PolymerMatrix = 22;
            elseif strcmp(app.PolymermatrixDropDown.Value, 'Waterborne polyurethane') == 1
                PolymerMatrix = 23;
                %disp(1)
            end
            %
            if strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Amine-modified') == 1
                CNTModification = 1;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'C18-alkylated') == 1
                CNTModification = 2;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'COOH-modified') == 1
                CNTModification = 3;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Diisocyanate functionalized') == 1
                CNTModification = 4;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Gum Arabic-modified') == 1
                CNTModification = 5;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Hydroxy-modified') == 1
                CNTModification = 6;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'MA-modified') == 1
                CNTModification = 7;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'NH2-modified') == 1
                CNTModification = 8;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Octyl-modified') == 1
                CNTModification = 9;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Oxidized') == 1
                CNTModification = 10;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'PBMA-grafted') == 1
                CNTModification = 11;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'PE-grafted') == 1
                CNTModification = 12;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'PHT-g-PMMA modified') == 1
                CNTModification = 13;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'PMMA-grafted') == 1
                CNTModification = 14;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Phenoxy-grafted') == 1
                CNTModification = 15;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Pristine') == 1
                CNTModification = 16;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Pristine with P3HT-g-PCL compatibilizer') == 1
                CNTModification = 17;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Purified') == 1
                CNTModification = 18;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Purified ') == 1
                CNTModification = 19;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Acid') == 1
                CNTModification = 20;
            elseif strcmp(app.CNTsurfacemodificationmethodDropDown.Value, 'Diamine') == 1
                CNTModification = 21;
            end
            
            if strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Ball milling') == 1
                ProcessingMethod = 1;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Bulk mixing') == 1
                ProcessingMethod = 2;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Electrospinning') == 1
                ProcessingMethod = 3;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Electrospinning–yarn twisting') == 1
                ProcessingMethod = 4;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Hot casting') == 1
                ProcessingMethod = 5;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'In situ condensation') == 1
                ProcessingMethod = 6;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'In situ polymerization') == 1
                ProcessingMethod = 7;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Mechanical blending') == 1
                ProcessingMethod = 8;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Melt blending') == 1
                ProcessingMethod = 9;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Melt extrusion') == 1
                ProcessingMethod = 10;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Melt fiber spinning') == 1
                ProcessingMethod = 11;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Melt mixing') == 1
                ProcessingMethod = 12;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Pan milling–melt mixing') == 1
                ProcessingMethod = 13;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Simple mixing') == 1
                ProcessingMethod = 14;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Solid state shear milling') == 1
                ProcessingMethod = 15;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Solid state shear pulverization') == 1
                ProcessingMethod = 16;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Solution blending') == 1
                ProcessingMethod = 17;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Solution casting') == 1
                ProcessingMethod = 18;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Solution mixing') == 1
                ProcessingMethod = 19;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Solution mixing–casting') == 1
                ProcessingMethod = 20;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Solution mixing–casting–curing') == 1
                ProcessingMethod = 21;
            elseif strcmp(app.NanocompositeprocessingmethodDropDown.Value, 'Solution mixing–injection ') == 1
                ProcessingMethod = 22;
            end
            
            PolymerDensity = app.Densityofpolymergcm3EditField.Value;
            PolymerYoung = app.YoungsmodulusofmatrixMPaEditField.Value;
            PolymerTensileStrength = app.TensilestrengthofmatrixMPaEditField.Value;
            weightFraction = app.CNTweightfractionEditField.Value;
            CNTDensity = app.CNTdensitygcm3EditField.Value;
            CNTDiameter = app.CNTaveragediameternmEditField.Value;
            CNTLength = app.CNTaveragelengthnmEditField.Value;
            CNTYoung = app.YoungsmodulusofCNTGPaEditField.Value;
            
            Inputs = [PolymerMatrix PolymerDensity PolymerYoung PolymerTensileStrength ...
                weightFraction CNTDensity CNTDiameter CNTLength CNTYoung ...
                CNTModification ProcessingMethod];
            
            pwd;
            currentFolder = pwd;
            %disp(currentFolder)
            cd(currentFolder)

            try
                load([currentFolder, '\GPR.mat'])
            catch exception
                fmsgbox = msgbox({'Please check the working directory!'; 'Operation stopped.'});
                return
            end
            try
                latestvalue = trainedModel.predictFcn(Inputs);
                if latestvalue <= 0
                    fmsgbox = msgbox({'Lack of data for this configuration!'; 'Operation stopped.'});
                else
                    app.TensilestrengthofnanocompositeMPaEditField.Value = latestvalue;  % Update value
                end
            catch exception
                fmsgbox = msgbox({'Lack of data for this configuration!'; 'Operation stopped.'});
                return
            end
            
            
            %if exist('GPR.mat') ~= 2; delete(fmsgbox); end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'UI Figure';
            app.Label = uilabel(app.GUI);
            app.Label.Text = {'Graphical User Interface (GUI) for predicting tensile strength of polymer carbon nanotube (CNT)'; ' composites based on machine-learning Gaussian Process Regression model'};
            app.Label_2 = uilabel(app.GUI);
            app.Label_2.Text = {'This GUI was developed by Dr. Tien-Thinh Le'; 'Email: tienthinhle.vn@gmail.com; '; '             tien-thinh.le@univ-paris-est.fr'; 'Created with Matlab 2018a'};
            app.InputvariablesPanel = uipanel(app.GUI);
            app.InputvariablesPanel.Title = 'Input variables';
            app.PolymermatrixDropDownLabel = uilabel(app.GUI);
            app.PolymermatrixDropDownLabel.Text = 'Polymer matrix';
            app.PolymermatrixDropDown = uidropdown(app.GUI);
            app.PolymermatrixDropDown.Items = {'Epoxy', 'High density polyethylene', 'Hard epoxy', 'Low density polyethylene', 'Nylon 6', 'Nylon 610', 'Polyacrylonitrile nanofibers', 'Polycarbonate', 'Polyamide 6', 'Polyetherimide', 'Poly(ethylene oxide)', 'Poly(ethylene terephthalate)', 'Polyimide', 'Acrylic acid grafted polylactide', 'Poly(methyl methacrylate) (PMMA)‐functionalized', 'Polypropylene', 'Polystyrene', 'Polyurethane', 'Polyvinyl alcohol', 'Poly(vinyl chloride)', 'Poly(styrene-b-butadiene-co-butylene-b-styrene)', 'Styrene–butadiene rubber', 'Waterborne polyurethane'};
            app.PolymermatrixDropDown.Value = 'Polystyrene';
            app.CNTsurfacemodificationmethodDropDownLabel = uilabel(app.GUI);
            app.CNTsurfacemodificationmethodDropDownLabel.Text = 'CNT surface modification method';
            app.CNTsurfacemodificationmethodDropDown = uidropdown(app.GUI);
            app.CNTsurfacemodificationmethodDropDown.Items = {'Amine-modified', 'C18-alkylated', 'COOH-modified', 'Diisocyanate functionalized', 'Gum Arabic-modified', 'Hydroxy-modified', 'MA-modified', 'NH2-modified', 'Octyl-modified', 'Oxidized', 'PBMA-grafted', 'PE-grafted', 'PHT-g-PMMA modified', 'PMMA-grafted', 'Phenoxy-grafted', 'Pristine', 'Pristine with P3HT-g-PCL compatibilizer', 'Purified', 'Acid', 'Diamine'};
            app.CNTsurfacemodificationmethodDropDown.Value = 'Oxidized';
            app.Densityofpolymergcm3EditFieldLabel = uilabel(app.GUI);
            app.Densityofpolymergcm3EditFieldLabel.Text = 'Density of polymer (g/cm^3)';
            app.Densityofpolymergcm3EditField = uieditfield(app.GUI, 'numeric');
            app.Densityofpolymergcm3EditField.Limits = [0.91 3.12];
            app.Densityofpolymergcm3EditField.ValueChangedFcn = createCallbackFcn(app, @Densityofpolymergcm3EditFieldValueChanged, true);
            app.Densityofpolymergcm3EditField.Value = 1.2;
            app.YoungsmodulusofmatrixMPaEditFieldLabel = uilabel(app.GUI);
            app.YoungsmodulusofmatrixMPaEditFieldLabel.Text = 'Young''s modulus of matrix (MPa)';
            app.YoungsmodulusofmatrixMPaEditField = uieditfield(app.GUI, 'numeric');
            app.YoungsmodulusofmatrixMPaEditField.Limits = [0.24 3060];
            app.YoungsmodulusofmatrixMPaEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsmodulusofmatrixMPaEditFieldValueChanged, true);
            app.YoungsmodulusofmatrixMPaEditField.Value = 986.47;
            app.TensilestrengthofmatrixMPaEditFieldLabel = uilabel(app.GUI);
            app.TensilestrengthofmatrixMPaEditFieldLabel.Text = 'Tensile strength of matrix (MPa)';
            app.TensilestrengthofmatrixMPaEditField = uieditfield(app.GUI, 'numeric');
            app.TensilestrengthofmatrixMPaEditField.Limits = [0.49 132];
            app.TensilestrengthofmatrixMPaEditField.ValueChangedFcn = createCallbackFcn(app, @TensilestrengthofmatrixMPaEditFieldValueChanged, true);
            app.TensilestrengthofmatrixMPaEditField.Value = 42.1;
            app.CNTweightfractionEditFieldLabel = uilabel(app.GUI);
            app.CNTweightfractionEditFieldLabel.Text = 'CNT weight fraction (%)';
            app.CNTweightfractionEditField = uieditfield(app.GUI, 'numeric');
            app.CNTweightfractionEditField.Limits = [0.01 20];
            app.CNTweightfractionEditField.ValueChangedFcn = createCallbackFcn(app, @CNTweightfractionEditFieldValueChanged, true);
            app.CNTweightfractionEditField.Value = 2.05;
            app.CNTdensitygcm3EditFieldLabel = uilabel(app.GUI);
            app.CNTdensitygcm3EditFieldLabel.Text = 'CNT density (g/cm^3)';
            app.CNTdensitygcm3EditField = uieditfield(app.GUI, 'numeric');
            app.CNTdensitygcm3EditField.Limits = [1.3 2.16];
            app.CNTdensitygcm3EditField.ValueChangedFcn = createCallbackFcn(app, @CNTdensitygcm3EditFieldValueChanged, true);
            app.CNTdensitygcm3EditField.Value = 1.94;
            app.CNTaveragediameternmEditFieldLabel = uilabel(app.GUI);
            app.CNTaveragediameternmEditFieldLabel.Text = 'CNT average diameter (nm)';
            app.CNTaveragediameternmEditField = uieditfield(app.GUI, 'numeric');
            app.CNTaveragediameternmEditField.Limits = [5.5 127.5];
            app.CNTaveragediameternmEditField.ValueChangedFcn = createCallbackFcn(app, @CNTaveragediameternmEditFieldValueChanged, true);
            app.CNTaveragediameternmEditField.Value = 32.01;
            app.CNTaveragelengthnmEditFieldLabel = uilabel(app.GUI);
            app.CNTaveragelengthnmEditFieldLabel.Text = 'CNT average length (nm)';
            app.CNTaveragelengthnmEditField = uieditfield(app.GUI, 'numeric');
            app.CNTaveragelengthnmEditField.Limits = [850 252500];
            app.CNTaveragelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @CNTaveragelengthnmEditFieldValueChanged, true);
            app.CNTaveragelengthnmEditField.Value = 26609.85;
            app.YoungsmodulusofCNTGPaEditFieldLabel = uilabel(app.GUI);
            app.YoungsmodulusofCNTGPaEditFieldLabel.Text = 'Young''s modulus of CNT (GPa)';
            app.YoungsmodulusofCNTGPaEditField = uieditfield(app.GUI, 'numeric');
            app.YoungsmodulusofCNTGPaEditField.Limits = [450 1100];
            app.YoungsmodulusofCNTGPaEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsmodulusofCNTGPaEditFieldValueChanged, true);
            app.YoungsmodulusofCNTGPaEditField.Value = 848.59;
            app.NanocompositeprocessingmethodDropDownLabel = uilabel(app.GUI);
            app.NanocompositeprocessingmethodDropDownLabel.Text = 'Nanocomposite processing method';
            app.NanocompositeprocessingmethodDropDown = uidropdown(app.GUI);
            app.NanocompositeprocessingmethodDropDown.Items = {'Ball milling', 'Bulk mixing', 'Electrospinning', 'Electrospinning–yarn twisting', 'Hot casting', 'In situ condensation', 'In situ polymerization', 'Mechanical blending', 'Melt blending', 'Melt extrusion', 'Melt fiber spinning', 'Melt mixing', 'Pan milling–melt mixing', 'Simple mixing', 'Solid state shear milling', 'Solid state shear pulverization', 'Solution blending', 'Solution casting', 'Solution mixing', 'Solution mixing–casting', 'Solution mixing–casting–curing', 'Solution mixing–injection molding'};
            app.NanocompositeprocessingmethodDropDown.Value = 'Melt blending';
            app.OutputPanel = uipanel(app.GUI);
            app.OutputPanel.Title = 'Output';
            app.TensilestrengthofnanocompositeMPaEditFieldLabel = uilabel(app.OutputPanel);
            app.TensilestrengthofnanocompositeMPaEditFieldLabel.Text = 'Tensile strength of nanocomposite (MPa)';
            app.TensilestrengthofnanocompositeMPaEditField = uieditfield(app.OutputPanel, 'numeric');
            app.StartpredictButton = uibutton(app.GUI, 'push');
            app.StartpredictButton.ButtonPushedFcn = createCallbackFcn(app, @StartpredictButtonPushed, true);
            app.StartpredictButton.Text = 'Start predict';
            app.RangeLabel = uilabel(app.GUI);
            app.RangeLabel.Text = 'Range:';
            app.RangeDensityPolymerSlider = uislider(app.GUI);
            app.RangeDensityPolymerSlider.Limits = [0.91 3.12];
            app.RangeDensityPolymerSlider.MajorTicks = [];
            app.RangeDensityPolymerSlider.MajorTickLabels = {};
            app.RangeDensityPolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeDensityPolymerSliderValueChanging, true);
            app.RangeDensityPolymerSlider.MinorTicks = [];
            app.RangeDensityPolymerSlider.Value = 1.2;
            app.Label_4 = uilabel(app.GUI);
            app.Label_4.Text = '3.12';
            app.Label_3 = uilabel(app.GUI);
            app.Label_3.Text = '0.91';
            app.RangeSlider_2Label = uilabel(app.GUI);
            app.RangeSlider_2Label.Text = 'Range:';
            app.RangeYoungPolymerSlider = uislider(app.GUI);
            app.RangeYoungPolymerSlider.Limits = [0.24 3060];
            app.RangeYoungPolymerSlider.MajorTicks = [];
            app.RangeYoungPolymerSlider.MajorTickLabels = {};
            app.RangeYoungPolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeYoungPolymerSliderValueChanged, true);
            app.RangeYoungPolymerSlider.MinorTicks = [];
            app.RangeYoungPolymerSlider.Value = 986.47;
            app.RangeSlider_2Label_2 = uilabel(app.GUI);
            app.RangeSlider_2Label_2.Text = 'Range:';
            app.RangeTensilePolymerSlider = uislider(app.GUI);
            app.RangeTensilePolymerSlider.Limits = [0.49 132];
            app.RangeTensilePolymerSlider.MajorTicks = [];
            app.RangeTensilePolymerSlider.MajorTickLabels = {};
            app.RangeTensilePolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeTensilePolymerSliderValueChanged, true);
            app.RangeTensilePolymerSlider.MinorTicks = [];
            app.RangeTensilePolymerSlider.Value = 42.1;
            app.RangeSlider_2Label_3 = uilabel(app.GUI);
            app.RangeSlider_2Label_3.Text = 'Range:';
            app.RangeWeightFractionSlider = uislider(app.GUI);
            app.RangeWeightFractionSlider.Limits = [0.01 20];
            app.RangeWeightFractionSlider.MajorTicks = [];
            app.RangeWeightFractionSlider.MajorTickLabels = {};
            app.RangeWeightFractionSlider.ValueChangedFcn = createCallbackFcn(app, @RangeWeightFractionSliderValueChanged, true);
            app.RangeWeightFractionSlider.MinorTicks = [];
            app.RangeWeightFractionSlider.Value = 2.05;
            app.RangeSlider_2Label_4 = uilabel(app.GUI);
            app.RangeSlider_2Label_4.Text = 'Range:';
            app.RangeCNTDensitySlider = uislider(app.GUI);
            app.RangeCNTDensitySlider.Limits = [1.3 2.16];
            app.RangeCNTDensitySlider.MajorTicks = [];
            app.RangeCNTDensitySlider.MajorTickLabels = {};
            app.RangeCNTDensitySlider.ValueChangedFcn = createCallbackFcn(app, @RangeCNTDensitySliderValueChanged, true);
            app.RangeCNTDensitySlider.MinorTicks = [];
            app.RangeCNTDensitySlider.Value = 1.94;
            app.RangeSlider_2Label_5 = uilabel(app.GUI);
            app.RangeSlider_2Label_5.Text = 'Range:';
            app.RangeCNTDiameterSlider = uislider(app.GUI);
            app.RangeCNTDiameterSlider.Limits = [5.5 127.5];
            app.RangeCNTDiameterSlider.MajorTicks = [];
            app.RangeCNTDiameterSlider.MajorTickLabels = {};
            app.RangeCNTDiameterSlider.ValueChangedFcn = createCallbackFcn(app, @RangeCNTDiameterSliderValueChanged, true);
            app.RangeCNTDiameterSlider.MinorTicks = [];
            app.RangeCNTDiameterSlider.Value = 32.01;
            app.RangeSlider_2Label_6 = uilabel(app.GUI);
            app.RangeSlider_2Label_6.Text = 'Range:';
            app.RangeCNTLengthSlider = uislider(app.GUI);
            app.RangeCNTLengthSlider.Limits = [850 252500];
            app.RangeCNTLengthSlider.MajorTicks = [];
            app.RangeCNTLengthSlider.MajorTickLabels = {};
            app.RangeCNTLengthSlider.ValueChangedFcn = createCallbackFcn(app, @RangeCNTLengthSliderValueChanged, true);
            app.RangeCNTLengthSlider.MinorTicks = [];
            app.RangeCNTLengthSlider.Value = 26609.85;
            app.RangeSlider_2Label_7 = uilabel(app.GUI);
            app.RangeSlider_2Label_7.Text = 'Range:';
            app.RangeCNTYoungSlider = uislider(app.GUI);
            app.RangeCNTYoungSlider.Limits = [450 1100];
            app.RangeCNTYoungSlider.MajorTicks = [];
            app.RangeCNTYoungSlider.MajorTickLabels = {};
            app.RangeCNTYoungSlider.ValueChangedFcn = createCallbackFcn(app, @RangeCNTYoungSliderValueChanged, true);
            app.RangeCNTYoungSlider.MinorTicks = [];
            app.RangeCNTYoungSlider.Value = 848.59;
            app.Label_5 = uilabel(app.GUI);
            app.Label_5.Text = '0.24';
            app.Label_7 = uilabel(app.GUI);
            app.Label_7.Text = '0.49';
            app.Label_9 = uilabel(app.GUI);
            app.Label_9.Text = '0.01';
            app.Label_11 = uilabel(app.GUI);
            app.Label_11.Text = '1.30';
            app.Label_13 = uilabel(app.GUI);
            app.Label_13.Text = '5.50';
            app.Label_15 = uilabel(app.GUI);
            app.Label_15.Text = '850';
            app.Label_17 = uilabel(app.GUI);
            app.Label_17.Text = '450';
            app.Label_6 = uilabel(app.GUI);
            app.Label_6.Text = '3060';
            app.Label_8 = uilabel(app.GUI);
            app.Label_8.Text = '132';
            app.Label_10 = uilabel(app.GUI);
            app.Label_10.Text = '20';
            app.Label_12 = uilabel(app.GUI);
            app.Label_12.Text = '2.16';
            app.Label_14 = uilabel(app.GUI);
            app.Label_14.Text = '127.50';
            app.Label_16 = uilabel(app.GUI);
            app.Label_16.Text = '252500';
            app.Label_18 = uilabel(app.GUI);
            app.Label_18.Text = '1100';
            app.MinLabel = uilabel(app.GUI);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.GUI);
            app.MaxLabel.Text = 'Max';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NanocompositeTensileStrengthPrediction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end